classdef PicamFlowAqApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PiCameraRemoteApp            matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        LocalModeMenu                matlab.ui.container.Menu
        PiCameraRemoteModeMenu       matlab.ui.container.Menu
        WebcamRemoteModeMenu         matlab.ui.container.Menu
        EditMenu                     matlab.ui.container.Menu
        EditSubMenu                  matlab.ui.container.Menu
        UploadSubMenu                matlab.ui.container.Menu
        HelpMenu                     matlab.ui.container.Menu
        SeeDocsMenu                  matlab.ui.container.Menu
        SeelunaFlowMenu              matlab.ui.container.Menu
        SeeInAppHelpMenu             matlab.ui.container.Menu
        MQTTprotocolsettingsPanel    matlab.ui.container.Panel
        LaunchBroker                 matlab.ui.control.Button
        Device_1_Check               matlab.ui.control.CheckBox
        Device_2_Check               matlab.ui.control.CheckBox
        Device_3_Check               matlab.ui.control.CheckBox
        Device_4_Check               matlab.ui.control.CheckBox
        Device1Label                 matlab.ui.control.Label
        Device2Label                 matlab.ui.control.Label
        Device3Label                 matlab.ui.control.Label
        Device4Label                 matlab.ui.control.Label
        IPEditFieldLabel             matlab.ui.control.Label
        IPBrokerField                matlab.ui.control.EditField
        IPEditFieldLabel_2           matlab.ui.control.Label
        IPSubscriberField_1          matlab.ui.control.EditField
        IPEditFieldLabel_3           matlab.ui.control.Label
        IPSubscriberField_2          matlab.ui.control.EditField
        IPEditFieldLabel_4           matlab.ui.control.Label
        IPSubscriberField_3          matlab.ui.control.EditField
        IPEditFieldLabel_5           matlab.ui.control.Label
        IPSubscriberField_4          matlab.ui.control.EditField
        breakButton                  matlab.ui.control.Button
        rebootButton                 matlab.ui.control.Button
        testButton                   matlab.ui.control.Button
        clc                          matlab.ui.control.Button
        readMsgs                     matlab.ui.control.Button
        cameraControlPanel           matlab.ui.container.Panel
        snapButton                   matlab.ui.control.Button
        importSnapButton             matlab.ui.control.Button
        viewSnapButton               matlab.ui.control.Button
        saveSnapButton               matlab.ui.control.Button
        Button                       matlab.ui.control.Button
        importVidButton              matlab.ui.control.Button
        saveVidButton                matlab.ui.control.Button
        viewVidButton                matlab.ui.control.Button
        manualButton                 matlab.ui.control.Button
        autoButton                   matlab.ui.control.Button
        lumenButton                  matlab.ui.control.Button
        snapSaveEditField            matlab.ui.control.EditField
        vidSaveEditField             matlab.ui.control.EditField
        CalibrationLabel             matlab.ui.control.Label
        VideoLabel                   matlab.ui.control.Label
        timesEditFieldLabel          matlab.ui.control.Label
        videoTimeField               matlab.ui.control.NumericEditField
        TabGroup                     matlab.ui.container.TabGroup
        Device1Tab                   matlab.ui.container.Tab
        messageToRemove              matlab.ui.control.Label
        Device2Tab                   matlab.ui.container.Tab
        Device3Tab                   matlab.ui.container.Tab
        Device4Tab                   matlab.ui.container.Tab
        StartPanel                   matlab.ui.container.Panel
        LaunchMosquitto              matlab.ui.control.Button
        Login                        matlab.ui.control.Button
        MatlabSSH                    matlab.ui.control.Button
        openPutty                    matlab.ui.control.Button
        launchLabel                  matlab.ui.control.Label
        loginLabel                   matlab.ui.control.Label
        SSHLabel                     matlab.ui.control.Label
        troubleshootLabel            matlab.ui.control.Label
        ProjectFolderEditFieldLabel  matlab.ui.control.Label
        ProjectFolderEditField       matlab.ui.control.EditField
        browseButton                 matlab.ui.control.Button
        commandLog                   matlab.ui.control.TextArea
        Image                        matlab.ui.control.Image
        Image2                       matlab.ui.control.Image
        Panel                        matlab.ui.container.Panel
        PiCamFlowAppLabel            matlab.ui.control.Label
    end

    
    properties (Access = private)
        % mosquitto launch
        exeFile
        startInfo
        proc
        
        % login
        username = "pi";
        password = "raspberry";
        
    end
    
    properties (Access = public)
        % system
        numDevices
        broker
        device = []
        lunaflowSubscriber
        
        % snapshot
        img
        montageStack
        stereo
        
    end
    
    methods (Access = private)
        
        % callback for GUI command prompt
        function setPromptFcn(app,jTextArea, eventData, newPrompt)
            % Prevent overlapping reentry due to prompt replacement
            persistent inProgress
            if isempty(inProgress)
                inProgress = 1;  %#ok unused
            else
                return;
            end
            
            try
                cwText = char(jTextArea.getText);
                app.commandLog.Value = cwText;
                pause(0.02);
            catch
            end
            
            % Enable new callbacks now that the prompt has been modified
            inProgress = [];
            
        end  % setPromptFcn
        
        function launchMosquittoWindows(app)
            
            app.exeFile = 'mosquitto.exe';
            app.startInfo = System.Diagnostics.ProcessStartInfo('cmd.exe', ...
                sprintf('/c "%s"', app.exeFile));
            app.startInfo.WindowStyle = System.Diagnostics.ProcessWindowStyle.Hidden;
            app.proc = System.Diagnostics.Process.Start(app.startInfo);
            if isempty(app.proc)
                error('Failed to launch process');
            end
            while true
                if app.proc.HasExited
                    fprintf('Mosquitto running.');
                    break
                end
                fprintf('.');
                pause(1.0);
            end
            
        end
    
        function numDevices = countDevices(app)
            numDevices = app.Device_1_Check.Value + app.Device_2_Check.Value + app.Device_3_Check.Value + app.Device_4_Check.Value;
        end
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                       
            % start-up for GUI command prompt
            try
                jDesktop = com.mathworks.mde.desk.MLDesktop.getInstance;
                jCmdWin = jDesktop.getClient('Command Window');
                jTextArea = jCmdWin.getComponent(0).getViewport.getView;
                set(jTextArea,'CaretUpdateCallback',@app.setPromptFcn)
            catch
                warndlg('fatal error');
            end
            
            % load current directory as project folder
            app.ProjectFolderEditField.Value = cd;
            
            % welcome message
            app.commandLog.Value = ['Welcome to PiCamera remote mode.'];
            
        end

        % Button pushed function: LaunchMosquitto
        function LaunchMosquittoButtonPushed(app, event)
            try
                launchMosquittoWindows(app)
            catch
                warndlg('Error launching mosquitto.exe. To run the app, remember to download & install mosquitto from mosquitto.org/download and add it to your environment variables.')
            end
        end

        % Button pushed function: Login
        function LoginButtonPushed(app, event)
            [app.username, app.password] = logindlg;
            app.username = convertCharsToStrings(app.username);
            app.password = convertCharsToStrings(app.password);
        end

        % Button pushed function: clc
        function clcButtonPushed(app, event)
            clc
        end

        % Button pushed function: MatlabSSH
        function MatlabSSHButtonPushed(app, event)
            app.numDevices = countDevices(app);
            
            for i = 1:app.numDevices
                try
                    app.device(i) = [];
                end
                if i == 1
                   app.device = raspi(eval(['app.IPSubscriberField_' num2str(i) '.Value']), app.username, app.password);
                else
                    app.device(i) = raspi(eval(['app.IPSubscriberField_' num2str(i) '.Value']), app.username, app.password);
                end
                fprintf(['\nDevice ' num2str(i) ' connected on pi@' eval(['app.IPSubscriberField_' num2str(i) '.Value']) '.'])
            end
        end

        % Button pushed function: LaunchBroker
        function LaunchBrokerButtonPushed(app, event)
            % create MQTT broker on PC (ensure mosquitto enabled)
            try
                app.broker = mqtt(['tcp://' app.IPBrokerField.Value], 'Port', 1883, 'KeepAlive', 60);
                fprintf('\nMQTT channel created.\n')
            catch
                warndlg('Error launching PC as the MQTT Broker. Ensure mosquitto.exe is running. Check IP addresses.')
            end
            
            %  subscribe to MQTT lunaflow channel on this PC
            try
                app.lunaflowSubscriber = subscribe(app.broker, 'camflow', 'callback',@showmessage);
                fprintf('\nSubscribed to camflow topic.\n')
            catch
                warndlg('Error starting camflow subscription. Make sure the broker has been launched.')
            end
            
            function showmessage(topic, data)
                disp(join(['MQTT msg : ' topic ':' data]));
            end

        end

        % Button pushed function: openPutty
        function openPuttyButtonPushed(app, event)
            app.numDevices = countDevices(app);
            
            for i = 1:app.numDevices
                app.device(i).openShell()
            end
        end

        % Button pushed function: testButton
        function testButtonPushed(app, event)
            publish(app.broker, 'camflow', 'test') 
        end

        % Button pushed function: breakButton
        function breakButtonPushed(app, event)
            publish(app.broker, 'camflow', 'break') 
        end

        % Button pushed function: rebootButton
        function rebootButtonPushed(app, event)
            for i = 1:app.numDevices
                try
                    system(app.device(i), 'reboot -h', 'sudo')
                end
                fprintf(['Rebooting device ' num2str(i) '. SSH to re-establish connection.'])
            end
        end

        % Button pushed function: snapButton
        function snapButtonPushed(app, event)
            publish(app.broker, 'camflow', 'snapshot') 
        end

        % Button pushed function: importSnapButton
        function importSnapButtonPushed(app, event)
            cd(app.ProjectFolderEditField.Value) 
            for i = 1:app.numDevices
                mkdir(['device' num2str(i)])
                addpath(['device' num2str(i)])
            end
            
            for i = 1:app.numDevices
                getFile(app.device(i), './camFlow/frame.jpg', ['device' num2str(i)])
                fprintf(['File transfer from device ' num2str(i) ' successful.\n'])
            end
            
            for i = 1:app.numDevices
                app.img(i).frame = imread(['device' num2str(i) '/frame.jpg']);
                app.img(i).frame_double = im2double(app.img(i).frame);
                app.img(i).frame_mono = rgb2gray(app.img(i).frame);
                app.montageStack(:,:,:,i) = app.img(i).frame_double;
                fprintf(['In-app import and B&W conversion from device ' num2str(i) ' successful.\n'])
            end
        end

        % Button pushed function: viewSnapButton
        function viewSnapButtonPushed(app, event)
            try
                montage(app.montageStack);
            catch
                warndlg('No image(s) stored in app.')
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            publish(app.broker, 'camflow', ['video -t ' num2str(app.videoTimeField.Value)]) 
        end

        % Button pushed function: importVidButton
        function importVidButtonPushed(app, event)
            for i = 1:app.numDevices
                try
                    getFile(app.device(i), ['./camFlow/video.h264'], ['device' num2str(i)])
                    fprintf(['File transfer from device ' num2str(i) ' successful.\n'])
                end
            end

        end

        % Button pushed function: autoButton
        function autoButtonPushed(app, event)
            for i = 2:app.numDevices
                % detect
                app.stereo(i).blobs1 = detectSURFFeatures(app.img(1).frame_mono, 'MetricThreshold', 50);
                app.stereo(i).blobs2 = detectSURFFeatures(app.img(i).frame_mono, 'MetricThreshold', 50);
                
                % extract
                [app.stereo(i).features1, app.stereo(i).validBlobs1] = extractFeatures(app.img(1).frame_mono, app.stereo(i).blobs1);
                [app.stereo(i).features2, app.stereo(i).validBlobs2] = extractFeatures(app.img(i).frame_mono, app.stereo(i).blobs2);
                
                % match
                app.stereo(i).indexPairs = matchFeatures(app.stereo(i).features1, app.stereo(i).features2, 'Metric', 'SAD','MatchThreshold', 5);
                
                app.stereo(i).matchedPoints1 = app.stereo(i).validBlobs1(app.stereo(i).indexPairs(:,1),:);
                app.stereo(i).matchedPoints2 = app.stereo(i).validBlobs2(app.stereo(i).indexPairs(:,2),:);
                
                % show (putatively) matched points
                figure('color','w')
                showMatchedFeatures(app.img(1).frame_mono, app.img(i).frame_mono, app.stereo(i).matchedPoints1, app.stereo(i).matchedPoints2);
                legend('Putatively matched points in frame 1', 'Putatively matched points in frame 2');
                
                
                % estimate matrix
                [app.stereo(i).fMatrix, app.stereo(i).epipolarInliers, app.stereo(i).status] = estimateFundamentalMatrix(app.stereo(i).matchedPoints1, app.stereo(i).matchedPoints2, 'Method', 'RANSAC', ...
                    'NumTrials', 10000, 'DistanceThreshold', 0.1, 'Confidence', 99.99);
                
                % calibration fail
                if app.stereo(i).status ~= 0 || isEpipoleInImage(app.stereo(i).fMatrix, size(app.img(1).frame_mono)) ...
                        || isEpipoleInImage(app.stereo(i).fMatrix', size(app.img(i).frame_mono))
                    warndlg(['Either not enough matching points were found or '...
                        'the epipoles are inside the images. You may need to '...
                        'inspect and improve the quality of detected features ',...
                        'and/or improve the quality of your images.']);
                    return
                end
                
                app.stereo(i).inlierPoints1 = app.stereo(i).matchedPoints1(app.stereo(i).epipolarInliers, :);
                app.stereo(i).inlierPoints2 = app.stereo(i).matchedPoints2(app.stereo(i).epipolarInliers, :);
                
                [app.stereo(i).t1, app.stereo(i).t2] = estimateUncalibratedRectification(app.stereo(i).fMatrix, ...
                    app.stereo(i).inlierPoints1.Location, app.stereo(i).inlierPoints2.Location, size(app.img(i).frame_mono));
                app.stereo(i).tform1 = projective2d(app.stereo(i).t1);
                app.stereo(i).tform2 = projective2d(app.stereo(i).t2);
                
                [app.stereo(i).snap1Rect, app.stereo(i).snap2Rect] = rectifyStereoImages(app.img(1).frame_mono, app.img(i).frame_mono, app.stereo(i).tform1, app.stereo(i).tform2);
                
                figure('color','w')
                imshow(stereoAnaglyph(app.stereo(i).snap1Rect, app.stereo(i).snap2Rect));
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PiCameraRemoteApp = uifigure('Visible', 'off');
            app.PiCameraRemoteApp.Name = 'UI Figure';
            app.FileMenu = uimenu(app.PiCameraRemoteApp);
            app.FileMenu.Text = 'File';
            app.LocalModeMenu = uimenu(app.FileMenu);
            app.LocalModeMenu.Text = 'Local mode';
            app.PiCameraRemoteModeMenu = uimenu(app.FileMenu);
            app.PiCameraRemoteModeMenu.Text = 'PiCamera remote mode';
            app.WebcamRemoteModeMenu = uimenu(app.FileMenu);
            app.WebcamRemoteModeMenu.Text = 'Webcam remote mode';
            app.EditMenu = uimenu(app.PiCameraRemoteApp);
            app.EditMenu.Text = 'Edit';
            app.EditSubMenu = uimenu(app.EditMenu);
            app.EditSubMenu.Text = 'Edit subscriber';
            app.UploadSubMenu = uimenu(app.EditMenu);
            app.UploadSubMenu.Text = 'Upload subscriber';
            app.HelpMenu = uimenu(app.PiCameraRemoteApp);
            app.HelpMenu.Text = 'Help';
            app.SeeDocsMenu = uimenu(app.HelpMenu);
            app.SeeDocsMenu.Text = 'See docs';
            app.SeelunaFlowMenu = uimenu(app.HelpMenu);
            app.SeelunaFlowMenu.Text = 'See lunaFlow project';
            app.SeeInAppHelpMenu = uimenu(app.HelpMenu);
            app.SeeInAppHelpMenu.Text = 'See in-app help';
            app.MQTTprotocolsettingsPanel = uipanel(app.PiCameraRemoteApp);
            app.MQTTprotocolsettingsPanel.Title = 'MQTT protocol settings';
            app.LaunchBroker = uibutton(app.MQTTprotocolsettingsPanel, 'push');
            app.LaunchBroker.ButtonPushedFcn = createCallbackFcn(app, @LaunchBrokerButtonPushed, true);
            app.LaunchBroker.Text = ' MQTT broker';
            app.Device_1_Check = uicheckbox(app.MQTTprotocolsettingsPanel);
            app.Device_1_Check.Text = '';
            app.Device_1_Check.Value = true;
            app.Device_2_Check = uicheckbox(app.MQTTprotocolsettingsPanel);
            app.Device_2_Check.Text = '';
            app.Device_3_Check = uicheckbox(app.MQTTprotocolsettingsPanel);
            app.Device_3_Check.Text = '';
            app.Device_4_Check = uicheckbox(app.MQTTprotocolsettingsPanel);
            app.Device_4_Check.Text = '';
            app.Device1Label = uilabel(app.MQTTprotocolsettingsPanel);
            app.Device1Label.Text = 'Device 1';
            app.Device2Label = uilabel(app.MQTTprotocolsettingsPanel);
            app.Device2Label.Text = 'Device 2';
            app.Device3Label = uilabel(app.MQTTprotocolsettingsPanel);
            app.Device3Label.Text = 'Device 3';
            app.Device4Label = uilabel(app.MQTTprotocolsettingsPanel);
            app.Device4Label.Text = 'Device 4';
            app.IPEditFieldLabel = uilabel(app.MQTTprotocolsettingsPanel);
            app.IPEditFieldLabel.Text = 'IP';
            app.IPBrokerField = uieditfield(app.MQTTprotocolsettingsPanel, 'text');
            app.IPBrokerField.Value = '192.168.58.2';
            app.IPEditFieldLabel_2 = uilabel(app.MQTTprotocolsettingsPanel);
            app.IPEditFieldLabel_2.Text = 'IP';
            app.IPSubscriberField_1 = uieditfield(app.MQTTprotocolsettingsPanel, 'text');
            app.IPSubscriberField_1.Value = '192.168.58.1';
            app.IPEditFieldLabel_3 = uilabel(app.MQTTprotocolsettingsPanel);
            app.IPEditFieldLabel_3.Text = 'IP';
            app.IPSubscriberField_2 = uieditfield(app.MQTTprotocolsettingsPanel, 'text');
            app.IPSubscriberField_2.Value = '192.168.57.3';
            app.IPEditFieldLabel_4 = uilabel(app.MQTTprotocolsettingsPanel);
            app.IPEditFieldLabel_4.Text = 'IP';
            app.IPSubscriberField_3 = uieditfield(app.MQTTprotocolsettingsPanel, 'text');
            app.IPSubscriberField_3.Value = '192.168.57.x';
            app.IPEditFieldLabel_5 = uilabel(app.MQTTprotocolsettingsPanel);
            app.IPEditFieldLabel_5.Text = 'IP';
            app.IPSubscriberField_4 = uieditfield(app.MQTTprotocolsettingsPanel, 'text');
            app.IPSubscriberField_4.Value = '192.168.57.x';
            app.breakButton = uibutton(app.MQTTprotocolsettingsPanel, 'push');
            app.breakButton.ButtonPushedFcn = createCallbackFcn(app, @breakButtonPushed, true);
            app.breakButton.Text = 'break';
            app.rebootButton = uibutton(app.MQTTprotocolsettingsPanel, 'push');
            app.rebootButton.ButtonPushedFcn = createCallbackFcn(app, @rebootButtonPushed, true);
            app.rebootButton.Text = 'reboot';
            app.testButton = uibutton(app.MQTTprotocolsettingsPanel, 'push');
            app.testButton.ButtonPushedFcn = createCallbackFcn(app, @testButtonPushed, true);
            app.testButton.Text = 'test';
            app.clc = uibutton(app.PiCameraRemoteApp, 'push');
            app.clc.ButtonPushedFcn = createCallbackFcn(app, @clcButtonPushed, true);
            app.clc.Text = 'clc';
            app.readMsgs = uibutton(app.PiCameraRemoteApp, 'push');
            app.readMsgs.Text = 'read';
            app.cameraControlPanel = uipanel(app.PiCameraRemoteApp);
            app.cameraControlPanel.Title = 'PiCamera control';
            app.snapButton = uibutton(app.cameraControlPanel, 'push');
            app.snapButton.ButtonPushedFcn = createCallbackFcn(app, @snapButtonPushed, true);
            app.snapButton.Icon = 'snap_logo.png';
            app.snapButton.Text = '';
            app.importSnapButton = uibutton(app.cameraControlPanel, 'push');
            app.importSnapButton.ButtonPushedFcn = createCallbackFcn(app, @importSnapButtonPushed, true);
            app.importSnapButton.Icon = 'import_snap_logo.png';
            app.importSnapButton.Text = '';
            app.viewSnapButton = uibutton(app.cameraControlPanel, 'push');
            app.viewSnapButton.ButtonPushedFcn = createCallbackFcn(app, @viewSnapButtonPushed, true);
            app.viewSnapButton.Icon = 'view_snap_logo.png';
            app.viewSnapButton.Text = '';
            app.saveSnapButton = uibutton(app.cameraControlPanel, 'push');
            app.saveSnapButton.Icon = 'save_snap_logo.png';
            app.saveSnapButton.Text = '';
            app.Button = uibutton(app.cameraControlPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'video_logo.png';
            app.Button.Text = '';
            app.importVidButton = uibutton(app.cameraControlPanel, 'push');
            app.importVidButton.ButtonPushedFcn = createCallbackFcn(app, @importVidButtonPushed, true);
            app.importVidButton.Icon = 'import_vid_logo.png';
            app.importVidButton.Text = '';
            app.saveVidButton = uibutton(app.cameraControlPanel, 'push');
            app.saveVidButton.Icon = 'save_vid_logo.png';
            app.saveVidButton.Text = '';
            app.viewVidButton = uibutton(app.cameraControlPanel, 'push');
            app.viewVidButton.Icon = 'view_vid_logo.png';
            app.viewVidButton.Text = '';
            app.manualButton = uibutton(app.cameraControlPanel, 'push');
            app.manualButton.Text = 'manual';
            app.autoButton = uibutton(app.cameraControlPanel, 'push');
            app.autoButton.ButtonPushedFcn = createCallbackFcn(app, @autoButtonPushed, true);
            app.autoButton.Text = 'auto ';
            app.lumenButton = uibutton(app.cameraControlPanel, 'push');
            app.lumenButton.Text = 'lumen';
            app.snapSaveEditField = uieditfield(app.cameraControlPanel, 'text');
            app.snapSaveEditField.Value = 'myFrame';
            app.vidSaveEditField = uieditfield(app.cameraControlPanel, 'text');
            app.vidSaveEditField.Value = 'myVideo';
            app.CalibrationLabel = uilabel(app.cameraControlPanel);
            app.CalibrationLabel.Text = 'Calibration';
            app.VideoLabel = uilabel(app.cameraControlPanel);
            app.VideoLabel.Text = 'Video';
            app.timesEditFieldLabel = uilabel(app.cameraControlPanel);
            app.timesEditFieldLabel.Text = 'time (s)';
            app.videoTimeField = uieditfield(app.cameraControlPanel, 'numeric');
            app.videoTimeField.Limits = [0 Inf];
            app.videoTimeField.Value = 5;
            app.TabGroup = uitabgroup(app.PiCameraRemoteApp);
            app.Device1Tab = uitab(app.TabGroup);
            app.Device1Tab.Title = 'Device 1';
            app.messageToRemove = uilabel(app.Device1Tab);
            app.messageToRemove.Text = 'camera settings under development';
            app.Device2Tab = uitab(app.TabGroup);
            app.Device2Tab.Title = 'Device 2';
            app.Device3Tab = uitab(app.TabGroup);
            app.Device3Tab.Title = 'Device 3';
            app.Device4Tab = uitab(app.TabGroup);
            app.Device4Tab.Title = 'Device 4';
            app.StartPanel = uipanel(app.PiCameraRemoteApp);
            app.StartPanel.Title = 'Start';
            app.LaunchMosquitto = uibutton(app.StartPanel, 'push');
            app.LaunchMosquitto.ButtonPushedFcn = createCallbackFcn(app, @LaunchMosquittoButtonPushed, true);
            app.LaunchMosquitto.Icon = 'Mosquitto-logo.png';
            app.LaunchMosquitto.Text = '';
            app.Login = uibutton(app.StartPanel, 'push');
            app.Login.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.Login.Icon = 'password_logo.PNG';
            app.Login.Text = '';
            app.MatlabSSH = uibutton(app.StartPanel, 'push');
            app.MatlabSSH.ButtonPushedFcn = createCallbackFcn(app, @MatlabSSHButtonPushed, true);
            app.MatlabSSH.Icon = 'SSH_logo.PNG';
            app.MatlabSSH.Text = '';
            app.openPutty = uibutton(app.StartPanel, 'push');
            app.openPutty.ButtonPushedFcn = createCallbackFcn(app, @openPuttyButtonPushed, true);
            app.openPutty.Icon = 'putty_logo.jpg';
            app.openPutty.Text = '';
            app.launchLabel = uilabel(app.StartPanel);
            app.launchLabel.Text = '1. launch';
            app.loginLabel = uilabel(app.StartPanel);
            app.loginLabel.Text = '2. log-in';
            app.SSHLabel = uilabel(app.StartPanel);
            app.SSHLabel.Text = '3. SSH';
            app.troubleshootLabel = uilabel(app.StartPanel);
            app.troubleshootLabel.Text = 'troubleshoot';
            app.ProjectFolderEditFieldLabel = uilabel(app.PiCameraRemoteApp);
            app.ProjectFolderEditFieldLabel.Text = 'Project Folder';
            app.ProjectFolderEditField = uieditfield(app.PiCameraRemoteApp, 'text');
            app.ProjectFolderEditField.Value = 'project folder';
            app.browseButton = uibutton(app.PiCameraRemoteApp, 'push');
            app.browseButton.Text = 'browse';
            app.commandLog = uitextarea(app.PiCameraRemoteApp);
            app.commandLog.Editable = 'off';
            app.commandLog.Value = {'>> Command log history'};
            app.Image = uiimage(app.PiCameraRemoteApp);
            app.Image.ImageSource = 'cam_crest.png';
            app.Image2 = uiimage(app.PiCameraRemoteApp);
            app.Image2.ImageSource = 'matlablogo.png';
            app.Panel = uipanel(app.PiCameraRemoteApp);
            app.PiCamFlowAppLabel = uilabel(app.Panel);
            app.PiCamFlowAppLabel.Text = 'PiCamFlowApp';
            % Show the figure after all components are created
            app.PiCameraRemoteApp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PicamFlowAqApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PiCameraRemoteApp)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PiCameraRemoteApp)
        end
    end
end