classdef camFlowAqApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        EditMenu                     matlab.ui.container.Menu
        ViewMenu                     matlab.ui.container.Menu
        HelpMenu                     matlab.ui.container.Menu
        seedocsMenu                  matlab.ui.container.Menu
        AcquisitionPanel             matlab.ui.container.Panel
        acquiresaveButton            matlab.ui.control.Button
        savebackgroundCheckBox       matlab.ui.control.CheckBox
        savecalibrationCheckBox      matlab.ui.control.CheckBox
        framesEditField_2Label       matlab.ui.control.Label
        framesEditField_2            matlab.ui.control.NumericEditField
        acquirebackgroundButton      matlab.ui.control.Button
        framesEditFieldLabel         matlab.ui.control.Label
        framesbackgroundEditField    matlab.ui.control.NumericEditField
        filenameoptEditFieldLabel    matlab.ui.control.Label
        filenameoptEditField         matlab.ui.control.EditField
        projectfolderEditFieldLabel  matlab.ui.control.Label
        projectfolderEditField       matlab.ui.control.EditField
        snapshotButton               matlab.ui.control.Button
        processstereoButton          matlab.ui.control.Button
        browseButton                 matlab.ui.control.Button
        Button                       matlab.ui.control.Button
        Button_2                     matlab.ui.control.Button
        PreviewtoolsPanel            matlab.ui.container.Panel
        FalsecolourDropDown          matlab.ui.control.DropDown
        previewButton                matlab.ui.control.Button
        cropButton                   matlab.ui.control.Button
        cropresetButton              matlab.ui.control.Button
        closepreviewButton           matlab.ui.control.Button
        cropSelect                   matlab.ui.control.DropDown
        falsecolourLabel             matlab.ui.control.Label
        cropselectLabel              matlab.ui.control.Label
        CalibrationtoolsPanel        matlab.ui.container.Panel
        calibratepxButton            matlab.ui.control.Button
        squaresizemmEditFieldLabel   matlab.ui.control.Label
        squaresizemmEditField        matlab.ui.control.NumericEditField
        calibratestereoButton        matlab.ui.control.Button
        linesizemmEditFieldLabel     matlab.ui.control.Label
        linesizemmEditField          matlab.ui.control.NumericEditField
        boardsizemmEditFieldLabel    matlab.ui.control.Label
        boardsizemmEditField         matlab.ui.control.NumericEditField
        CmdlogPanel                  matlab.ui.container.Panel
        CommandsTextArea             matlab.ui.control.TextArea
        clcButton                    matlab.ui.control.Button
        VideostreamPanel             matlab.ui.container.Panel
        CamerasettingsPanel          matlab.ui.container.Panel
        cam1KnobLabel                matlab.ui.control.Label
        cam1Knob                     matlab.ui.control.Knob
        cam2Knob                     matlab.ui.control.Knob
        cam2KnobLabel                matlab.ui.control.Label
        cam3Knob                     matlab.ui.control.Knob
        cam3KnobLabel                matlab.ui.control.Label
        cam4Knob                     matlab.ui.control.Knob
        cam4KnobLabel                matlab.ui.control.Label
        ExposureLabel                matlab.ui.control.Label
        AQfpsLabel                   matlab.ui.control.Label
        Spinner                      matlab.ui.control.Spinner
        Spinner_2                    matlab.ui.control.Spinner
        Spinner_3                    matlab.ui.control.Spinner
        Spinner_4                    matlab.ui.control.Spinner
        CamTabGroup                  matlab.ui.container.TabGroup
        Cam1Tab                      matlab.ui.container.Tab
        loadCamera_1                 matlab.ui.control.Button
        resetButton                  matlab.ui.control.Button
        interface_1                  matlab.ui.control.DropDown
        format_1                     matlab.ui.control.DropDown
        deviceID_1                   matlab.ui.control.DropDown
        Cam2Tab                      matlab.ui.container.Tab
        loadCamera_2                 matlab.ui.control.Button
        resetButton_2                matlab.ui.control.Button
        interface_2                  matlab.ui.control.DropDown
        format_2                     matlab.ui.control.DropDown
        deviceID_2                   matlab.ui.control.DropDown
        Cam3Tab                      matlab.ui.container.Tab
        loadCamera_3                 matlab.ui.control.Button
        resetButton_3                matlab.ui.control.Button
        interface_3                  matlab.ui.control.DropDown
        format_3                     matlab.ui.control.DropDown
        deviceID_3                   matlab.ui.control.DropDown
        Cam4Tab                      matlab.ui.container.Tab
        loadCamera_4                 matlab.ui.control.Button
        resetButton_4                matlab.ui.control.Button
        interface_4                  matlab.ui.control.DropDown
        format_4                     matlab.ui.control.DropDown
        deviceID_4                   matlab.ui.control.DropDown
        activecamsPanel              matlab.ui.container.Panel
        CheckBox_1                   matlab.ui.control.CheckBox
        CheckBox_2                   matlab.ui.control.CheckBox
        CheckBox_3                   matlab.ui.control.CheckBox
        CheckBox_4                   matlab.ui.control.CheckBox
        lampCam_1                    matlab.ui.control.Lamp
        lampCam_2                    matlab.ui.control.Lamp
        lampCam_3                    matlab.ui.control.Lamp
        lampCam_4                    matlab.ui.control.Lamp
        quickloadButton              matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
        updateButton                 matlab.ui.control.StateButton
        updateButton_2               matlab.ui.control.StateButton
        VideoStream                  matlab.ui.control.UIAxes
        HistLabel                    matlab.ui.control.Label
        ColorHistLabel               matlab.ui.control.Label
    end

    
    properties (Access = public)
        camNum              % number of connected cameras
        cam1 = 0            % camera object from the camFlowAq class
        cam2 = 0            % camera object from the camFlowAq class
        cam3 = 0            % camera object from the camFlowAq class
        cam4 = 0            % camera object from the camFlowAq class
        sysInfo             % information about installed hardware adaptors
        intInfo             % information about devices
        
        % UI axis to create when previewing with multiple cameras
        VideoStream_1
        VideoStream_2
        VideoStream_3
        VideoStream_4
    end
    
    methods (Access = private)
        
        % callback GUI command prompt
        function setPromptFcn(app,jTextArea, eventData, newPrompt)
            % Prevent overlapping reentry due to prompt replacement
            persistent inProgress
            if isempty(inProgress)
                inProgress = 1;  %#ok unused
            else
                return;
            end
            
            try
                cwText = char(jTextArea.getText);
                app.CommandsTextArea.Value = cwText;
                pause(0.02);
            catch
            end
            
            % Enable new callbacks now that the prompt has been modified
            inProgress = [];
            
        end  % setPromptFcn
         
        % restores default camera selection
        function loadConnectedCamera_1(app)
            try
                for i = 1:numel(app.sysInfo.InstalledAdaptors)
                    % load connected camera as default
                    if numel(app.intInfo.Adaptor.info(i).DeviceIDs) >= 1
                        % interface
                        app.interface_1.Value = app.sysInfo.InstalledAdaptors{i};  % choose connected device as default
                        
                        % format
                        app.format_1.Items = app.intInfo.Adaptor.info(i).DeviceInfo(1).SupportedFormats;
                        app.format_1.ItemsData = app.intInfo.Adaptor.info(i).DeviceInfo(1).SupportedFormats;
                        app.format_1.Value = convertCharsToStrings(app.intInfo.Adaptor.info(i).DeviceInfo(1).DefaultFormat);
                        
                        % device ID
                        for j = 1:numel(app.intInfo.Adaptor.info(i).DeviceIDs)
                            tempID(j) = convertCharsToStrings(app.intInfo.Adaptor.info(i).DeviceInfo(j).DeviceName);
                        end
                        app.deviceID_1.Items = tempID;
                        app.deviceID_1.ItemsData = app.intInfo.Adaptor.info(i).DeviceIDs(:);
                        app.deviceID_1.Value = 1;
                    end
                end
            catch
                warndlg('Error in loadConnectedCamera_1.');
            end
        end     %loadConnectedCamera_1
        
        function loadConnectedCamera_2(app)
            try
                for i = 1:numel(app.sysInfo.InstalledAdaptors)
                    % load connected camera as default
                    if numel(app.intInfo.Adaptor.info(i).DeviceIDs) >= 2
                        % interface
                        app.interface_2.Value = app.sysInfo.InstalledAdaptors{i};  % choose connected device as default
                        
                        % format
                        app.format_2.Items = app.intInfo.Adaptor.info(i).DeviceInfo(2).SupportedFormats;
                        app.format_2.ItemsData = app.intInfo.Adaptor.info(i).DeviceInfo(2).SupportedFormats;
                        app.format_2.Value = convertCharsToStrings(app.intInfo.Adaptor.info(i).DeviceInfo(2).DefaultFormat);
                        
                        % device ID
                        for j = 1:numel(app.intInfo.Adaptor.info(i).DeviceIDs)
                            tempID(j) = convertCharsToStrings(app.intInfo.Adaptor.info(i).DeviceInfo(j).DeviceName);
                        end
                        app.deviceID_2.Items = tempID;
                        app.deviceID_2.ItemsData = app.intInfo.Adaptor.info(i).DeviceIDs(:);
                        app.deviceID_2.Value = 2;
                    end
                end
            catch
                warndlg('Error in loadConnectedCamera_2.');
            end
        end     %loadConnectedCamera_2
    
    function loadConnectedCamera_3(app)
            try
                for i = 1:numel(app.sysInfo.InstalledAdaptors)
                    % load connected camera as default
                    if numel(app.intInfo.Adaptor.info(i).DeviceIDs) >= 3
                        % interface
                        app.interface_3.Value = app.sysInfo.InstalledAdaptors{i};  % choose connected device as default
                        
                        % format
                        app.format_3.Items = app.intInfo.Adaptor.info(i).DeviceInfo(3).SupportedFormats;
                        app.format_3.ItemsData = app.intInfo.Adaptor.info(i).DeviceInfo(3).SupportedFormats;
                        app.format_3.Value = convertCharsToStrings(app.intInfo.Adaptor.info(i).DeviceInfo(3).DefaultFormat);
                        
                        % device ID
                        for j = 1:numel(app.intInfo.Adaptor.info(i).DeviceIDs)
                            tempID(j) = convertCharsToStrings(app.intInfo.Adaptor.info(i).DeviceInfo(j).DeviceName);
                        end
                        app.deviceID_3.Items = tempID;
                        app.deviceID_3.ItemsData = app.intInfo.Adaptor.info(i).DeviceIDs(:);
                        app.deviceID_3.Value = 3;
                    end
                end
            catch
                warndlg('Error in loadConnectedCamera_3.');
            end
        end     %loadConnectedCamera_3
    
    function loadConnectedCamera_4(app)
            try
                for i = 1:numel(app.sysInfo.InstalledAdaptors)
                    % load connected camera as default
                    if numel(app.intInfo.Adaptor.info(i).DeviceIDs) >= 4
                        % interface
                        app.interface_4.Value = app.sysInfo.InstalledAdaptors{i};  % choose connected device as default
                        
                        % format
                        app.format_4.Items = app.intInfo.Adaptor.info(i).DeviceInfo(4).SupportedFormats;
                        app.format_4.ItemsData = app.intInfo.Adaptor.info(i).DeviceInfo(4).SupportedFormats;
                        app.format_4.Value = convertCharsToStrings(app.intInfo.Adaptor.info(i).DeviceInfo(4).DefaultFormat);
                        
                        % device ID
                        for j = 1:numel(app.intInfo.Adaptor.info(i).DeviceIDs)
                            tempID(j) = convertCharsToStrings(app.intInfo.Adaptor.info(i).DeviceInfo(j).DeviceName);
                        end
                        app.deviceID_4.Items = tempID;
                        app.deviceID_4.ItemsData = app.intInfo.Adaptor.info(i).DeviceIDs(:);
                        app.deviceID_4.Value = 4;
                    end
                end
            catch
                warndlg('Error in loadConnectedCamera_4.');
            end
        end     %loadConnectedCamera_4
        
        function resetLampAll(app)
            app.lampCam_1.Color = 'red';
            app.lampCam_2.Color = 'red';
            app.lampCam_3.Color = 'red';
            app.lampCam_4.Color = 'red';
        end     % reset Lamp
        
        
        function [tempCamArray, tempCamNum] = createTempCamArray(app)
            tempCamArray = struct('cam',[]);
            tempCamNum = 0;
            for i = 1:4
                % if checkboxed ticked - append the camera object to the array
                if eval(convertCharsToStrings(['app.CheckBox_', num2str(i), '.Value'])) == 1
                     tempCamArray.cam  =   cat(1, tempCamArray.cam, eval(['app.cam' num2str(i)]));
                end
            end
        end     % tempCamArray
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % start-up handles for Video stream
            res = [2560, 2048];
            image(app.VideoStream ,zeros(res(2), res(1) ) );
            %             image(app.VideoStream,imread('fountainBlueOrange.PNG'));
            set(app.VideoStream,'visible','off')
            axis(app.VideoStream, 'tight')
            axis(app.VideoStream, 'equal')
            colormap(app.VideoStream,'gray')
            
            % start-up for GUI command prompt
            try
                jDesktop = com.mathworks.mde.desk.MLDesktop.getInstance;
                jCmdWin = jDesktop.getClient('Command Window');
                jTextArea = jCmdWin.getComponent(0).getViewport.getView;
                set(jTextArea,'CaretUpdateCallback',@app.setPromptFcn)
            catch
                warndlg('fatal error');
            end
            
            % maximise window
%             drawnow
%             app.UIFigure.WindowState = 'maximized';
            
            % load up camera and system information
%             inspectSystem(app)
            
            app.sysInfo = imaqhwinfo;   % list available interfaces
            if isempty(app.sysInfo.InstalledAdaptors) == 1
                warndlg('No camera hardware adaptors installed.')
            end
            
            % check connected device info
            tempCamNum = 0;
            for i = 1:numel(app.sysInfo.InstalledAdaptors)
                app.intInfo.Adaptor.type(i) = convertCharsToStrings(app.sysInfo.InstalledAdaptors{i});
                app.intInfo.Adaptor.info(i) = imaqhwinfo(app.intInfo.Adaptor.type(i));
                tempCamNum = tempCamNum + numel(app.intInfo.Adaptor.info(i).DeviceIDs);
            end
            app.camNum = tempCamNum;
            if app.camNum == 0
                warndlg('No cameras connected');
            end
            
            % load available interfaces into drop-down boxes
            app.interface_1.Items = app.sysInfo.InstalledAdaptors;
            app.interface_1.ItemsData = app.sysInfo.InstalledAdaptors;
            app.interface_2.Items = app.sysInfo.InstalledAdaptors;
            app.interface_2.ItemsData = app.sysInfo.InstalledAdaptors;
            app.interface_3.Items = app.sysInfo.InstalledAdaptors;
            app.interface_3.ItemsData = app.sysInfo.InstalledAdaptors;
            app.interface_4.Items = app.sysInfo.InstalledAdaptors;
            app.interface_4.ItemsData = app.sysInfo.InstalledAdaptors;

            % load available formats into drop-down boxes
            loadConnectedCamera_1(app);
            loadConnectedCamera_2(app);
            loadConnectedCamera_3(app);
            loadConnectedCamera_4(app);
            
            % load current directory as project folder
            app.projectfolderEditField.Value = cd;
            
            app.CommandsTextArea.Value = ['Welcome! ' num2str(tempCamNum) ' devices are connected.'];
        end

        % Callback function: CommandsTextArea, loadCamera_1
        function LoadCameraButtonPushed(app, event)
            disp('Loading camera 1...')
            try
                app.cam1 = camFlowAq(app.interface_1.Value, 1, app.format_1.Value);
                app.lampCam_1.Color = 'green';
            catch
                warndlg('Camera 1 load fail.');
            end
        end

        % Button pushed function: resetButton, resetButton_2, 
        % resetButton_3, resetButton_4
        function resetButton_1Pushed(app, event)
            imaqreset;
            disp('Resetting camera connections...')
            loadConnectedCamera_1(app);
            resetLampAll(app)
            app.CheckBox_1.Value = 1;
            app.CheckBox_2.Value = 0;
            app.CheckBox_3.Value = 0;
            app.CheckBox_4.Value = 0;
        end

        % Button pushed function: previewButton
        function previewButtonPushed(app, event)
                [tempPrevCamArray, tempCamNum] = createTempCamArray(app);
                
                try
                    previewApp(tempPrevCamArray(:).cam, app);
                catch
                    warndlg('Preview fail.');
                end
        end

        % Callback function: cropButton, cropSelect
        function cropButtonPushed(app, event)
                warndlg('Crop function not supported yet.');
%             try
%                 cropCamNum = eval(app.cropSelect.Value);
%                 cropApp(eval(['app.cam' num2str(app.cropSelect.Value)]), app, cropCamNum);

%                 h = rbbox(app.VideoStream);

%             catch
%                 warndlg('Crop fail.');
%             end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: clcButton
        function clcButtonPushed(app, event)
            clc;
        end

        % Callback function
        function camFormat_1ValueChanged(app, event)
% -----------------------------------------------------------------------------------
        end

        % Callback function
        function camInterface_1ValueChanged(app, event)
% ----------------------------------------------------------------------------------- 
        end

        % Button pushed function: closepreviewButton
        function closepreviewButtonPushed(app, event)
            try
                try
                    delete(app.VideoStream)
                catch
                end
                try
                    delete(app.VideoStream_1)
                catch
                end
                try
                    delete(app.VideoStream_2)
                catch
                end
                try
                    delete(app.VideoStream_3)
                catch
                end
                try
                    delete(app.VideoStream_4)
                catch
                end
                try
                    stoppreview(app.cam1.camera.vid)
                catch
                end
                try
                    stoppreview(app.cam2.camera.vid)
                catch
                end
                try
                    stoppreview(app.cam3.camera.vid)
                catch
                end
                try
                    stoppreview(app.cam4.camera.vid)
                catch
                end
                app.VideoStream = uiaxes(app.UIFigure);
                app.VideoStream.Color = [0.2706 0.2706 0.2706];
                app.VideoStream.BackgroundColor = [0.2706 0.2706 0.2706];
                app.VideoStream.Position = [59 396 623 393];
                res = [2560, 2048];
                image(app.VideoStream ,zeros(res(2), res(1) ) );
                set(app.VideoStream,'visible','off')
                axis(app.VideoStream, 'tight')
                axis(app.VideoStream, 'equal')
                colormap(app.VideoStream,'gray')
            catch
            end
        end

        % Value changed function: deviceID_1, format_1, interface_1
        function interface_1ValueChanged(app, event)
            value = app.interface_1.Value;
            try
                tempInfo = imaqhwinfo(value);
                
                % format
                app.format_1.Items = tempInfo.DeviceInfo.SupportedFormats;
                app.format_1.ItemsData = tempInfo.DeviceInfo.SupportedFormats;
                
                % device ID
                app.deviceID_1.Items = convertCharsToStrings(tempInfo.DeviceInfo(1).DeviceName);
                app.deviceID_1.ItemsData = tempInfo.DeviceIDs;
                app.deviceID_1.Value = 1;
            catch
                warndlg(['Interface_1ValueChanged error: Error in callback to load default camera names during ''interface'' change. Check whether camera(s) are connected.'])
                % go back to default connected camera
                loadConnectedCamera_1(app);
            end
        end

        % Button pushed function: loadCamera_2
        function loadCamera_2ButtonPushed(app, event)
            disp('Loading camera 2...')
            try
                app.cam2 = camFlowAq(app.interface_2.Value, 2, app.format_2.Value);
                app.lampCam_2.Color = 'green';
            catch
                warndlg('Camera 2 load fail.');
            end
        end

        % Value changed function: deviceID_2, format_2, interface_2
        function interface_2ValueChanged(app, event)
            value = app.interface_2.Value;
            
            try
                tempInfo = imaqhwinfo(value);
                
                % format
                app.format_2.Items = tempInfo.DeviceInfo.SupportedFormats;
                app.format_2.ItemsData = tempInfo.DeviceInfo.SupportedFormats;
                
                % device ID
                app.deviceID_2.Items = convertCharsToStrings(tempInfo.DeviceInfo(2).DeviceName);
                app.deviceID_2.ItemsData = tempInfo.DeviceIDs;
                try
                    app.deviceID_2.Value = 2;
                catch
                    app.deviceID_2.Value = 1;
                end
            catch
                warndlg(['Interface_2ValueChanged error: Error in callback to load default camera names during ''interface'' change. Check whether camera(s) are connected.'])
                % go back to default connected camera
                loadConnectedCamera_1(app);
            end
        end

        % Button pushed function: loadCamera_3
        function loadCamera_3ButtonPushed(app, event)
            disp('Loading camera 3...')
            try
                app.cam3 = camFlowAq(app.interface_3.Value, 3, app.format_3.Value);
                app.lampCam_3.Color = 'green';
            catch
                warndlg('Camera 3 load fail.');
            end
        end

        % Value changed function: deviceID_3, format_3, interface_3
        function interface_3ValueChanged(app, event)
            value = app.interface_3.Value;
            
            try
                tempInfo = imaqhwinfo(value);
                
                % format
                app.format_3.Items = tempInfo.DeviceInfo.SupportedFormats;
                app.format_3.ItemsData = tempInfo.DeviceInfo.SupportedFormats;
                
                % device ID
                app.deviceID_3.Items = convertCharsToStrings(tempInfo.DeviceInfo(3).DeviceName);
                app.deviceID_3.ItemsData = tempInfo.DeviceIDs;
                try
                    app.deviceID_2.Value = 3;
                catch
                    app.deviceID_2.Value = 1;
                end
            catch
                warndlg(['Interface_3ValueChanged error: Error in callback to load default camera names during ''interface'' change. Check whether camera(s) are connected.'])
                % go back to default connected camera
                loadConnectedCamera_1(app);
            end
        end

        % Button pushed function: loadCamera_4
        function loadCamera_4ButtonPushed(app, event)
            disp('Loading camera 4...')
            try
                app.cam4 = camFlowAq(app.interface_4.Value, 4, app.format_4.Value);
                app.lampCam_4.Color = 'green';
            catch
                warndlg('Camera 4 load fail.');
            end
        end

        % Value changed function: deviceID_4, format_4, interface_4
        function interface_4ValueChanged(app, event)
            value = app.interface_4.Value;
            
            try
                tempInfo = imaqhwinfo(value);
                
                % format
                app.format_4.Items = tempInfo.DeviceInfo.SupportedFormats;
                app.format_4.ItemsData = tempInfo.DeviceInfo.SupportedFormats;
                
                % device ID
                app.deviceID_4.Items = convertCharsToStrings(tempInfo.DeviceInfo(4).DeviceName);
                app.deviceID_4.ItemsData = tempInfo.DeviceIDs;
                try
                    app.deviceID_2.Value = 4;
                catch
                    app.deviceID_2.Value = 1;
                end
            catch
                warndlg(['Interface_4ValueChanged error: Error in callback to load default camera names during ''interface'' change. Check whether camera(s) are connected.'])
                % go back to default connected camera
                loadConnectedCamera_1(app);
            end
        end

        % Button pushed function: quickloadButton
        function quickloadButtonPushed(app, event)
            
            disp('Loading cameras (quick load)...')
            
            % camera 1
            if app.CheckBox_1.Value == 1
                try
                    app.cam1 = camFlowAq(app.interface_1.Value, 1, app.format_1.Value);
                    app.lampCam_1.Color = 'green';
                catch
                    warndlg('Quick load error: camera 1 fail.')
                    imaqreset;
                    resetLampAll(app);
                    return
                end
            end
            
            % camera 2
            if app.CheckBox_2.Value == 1
                try
                    app.cam2 = camFlowAq(app.interface_2.Value, 2, app.format_2.Value);
                    app.lampCam_2.Color = 'green';
                catch
                    warndlg('Quick load error: camera 2 fail.')
                    imaqreset;
                    resetLampAll(app);
                    return
                end
            end
            
            % camera 3
            if app.CheckBox_3.Value == 1
                try
                    app.cam3 = camFlowAq(app.interface_3.Value, 3, app.format_3.Value);
                    app.lampCam_3.Color = 'green';
                catch
                    warndlg('Quick load error: camera 3 fail.')
                    imaqreset;
                    resetLampAll(app);
                    return
                end
            end
            
            % camera 4
            if app.CheckBox_4.Value == 1
                try
                    app.cam4 = camFlowAq(app.interface_4.Value, 4, app.format_4.Value);
                    app.lampCam_4.Color = 'green';
                catch
                    warndlg('Quick load error: camera 4 fail.')
                    imaqreset;
                    resetLampAll(app);
                    return
                end
            end

        end

        % Value changed function: FalsecolourDropDown
        function FalsecolourDropDownValueChanged(app, event)
            value = app.FalsecolourDropDown.Value;
            app.VideoStream.Colormap = eval(convertCharsToStrings(value));
        end

        % Value changed function: cam1Knob
        function cam1KnobValueChanged(app, event)
            value = app.cam1Knob.Value;
            
            % webcam exposure mode
            if strcmp(app.interface_1.Value, 'winvideo') == 1
                try   % remove auto settings
                    app.cam1.camera.src.BacklightCompensation = 'off';
                    app.cam1.camera.src.WhiteBalanceMode = 'manual';
                catch
                    warndlg('Failed to change automatic settings.');
                end
                
                try
                    app.cam1.camera.src.Exposure = -value*9/11;
                catch
                    warndlg('Failed to change exposure.');
                end
            end
        end

        % Callback function
        function cam1KnobValueChanging(app, event)
%             changingValue = event.Value;
%             app.EditField_3 = round(changingValue);
        end

        % Callback function
        function EditField_3ValueChanged(app, event)
            
        end

        % Value changed function: cam2Knob
        function cam2KnobValueChanged(app, event)
            value = app.cam2Knob.Value;
            
            % webcam exposure mode
            if strcmp(app.interface_2.Value, 'winvideo') == 1
                try   % remove auto settings
                    app.cam2.camera.src.BacklightCompensation = 'off';
                    app.cam2.camera.src.WhiteBalanceMode = 'manual';
                catch
                    warndlg('Failed to change automatic settings.');
                end
                
                try
                    app.cam2.camera.src.Exposure = -value*9/11;
                catch
                    warndlg('Failed to change exposure.');
                end
            end
        end

        % Value changed function: cam3Knob
        function cam3KnobValueChanged(app, event)
            value = app.cam3Knob.Value;
            
            % webcam exposure mode
            if strcmp(app.interface_3.Value, 'winvideo') == 1
                try   % remove auto settings
                    app.cam3.camera.src.BacklightCompensation = 'off';
                    app.cam3.camera.src.WhiteBalanceMode = 'manual';
                catch
                    warndlg('Failed to change automatic settings.');
                end
                
                try
                    app.cam3.camera.src.Exposure = -value*9/11;
                catch
                    warndlg('Failed to change exposure.');
                end
            end
        end

        % Value changed function: cam4Knob
        function cam4KnobValueChanged(app, event)
            value = app.cam4Knob.Value;
            
            if strcmp(app.interface_4.Value, 'winvideo') == 1
                try   % remove auto settings
                    app.cam4.camera.src.BacklightCompensation = 'off';
                    app.cam4.camera.src.WhiteBalanceMode = 'manual';
                catch
                    warndlg('Failed to change automatic settings.');
                end
                
                try
                    app.cam4.camera.src.Exposure = -value*9/11;
                catch
                    warndlg('Failed to change exposure.');
                end
            end

        end

        % Value changed function: updateButton
        function updateButtonValueChanged(app, event)
            value = app.updateButton.Value;
            histogram(app.UIAxes, app.VideoStream.Children.CData, 255, 'EdgeAlpha', 0.2,  'FaceColor', [0.99 0.99 0.99],'FaceAlpha', 0.8);
        end

        % Value changed function: updateButton_2
        function updateButton_2ValueChanged(app, event)
            value = app.updateButton_2.Value;
            hold(app.UIAxes_2)
            hR = histogram(app.UIAxes_2, app.VideoStream.Children.CData(:,:,1), 255, 'EdgeAlpha', 0.2, 'FaceColor', [1.0 0.2 0.2],'FaceAlpha', 1);
            hG = histogram(app.UIAxes_2, app.VideoStream.Children.CData(:,:,2), 255, 'EdgeAlpha', 0.2,  'FaceColor', [0.2 1.0 0.2],'FaceAlpha', 0.5);
            hB = histogram(app.UIAxes_2, app.VideoStream.Children.CData(:,:,3), 255, 'EdgeAlpha', 0.2,  'FaceColor', [0.2 0.2 1.0],'FaceAlpha', 0.25);
            hold off
        end

        % Button pushed function: browseButton
        function browseButtonPushed(app, event)
            try
                app.projectfolderEditField.Value = uigetdir;
            catch
                disp('Project folder not selected')
            end
        end

        % Button pushed function: acquirebackgroundButton
        function acquirebackgroundButtonPushed(app, event)
        
            try
                acquireBackgroundApp(app)
            catch
                warndlg('Background acquisition fail.');
            end
            

        end

        % Button pushed function: snapshotButton
        function snapshotButtonPushed(app, event)
            snapshotApp(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.ViewMenu = uimenu(app.UIFigure);
            app.ViewMenu.Text = 'View';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.seedocsMenu = uimenu(app.HelpMenu);
            app.seedocsMenu.Text = 'see docs';
            app.AcquisitionPanel = uipanel(app.UIFigure);
            app.AcquisitionPanel.Title = 'Acquisition';
            app.acquiresaveButton = uibutton(app.AcquisitionPanel, 'push');
            app.acquiresaveButton.Text = 'acquire & save';
            app.savebackgroundCheckBox = uicheckbox(app.AcquisitionPanel);
            app.savebackgroundCheckBox.Text = 'save background';
            app.savebackgroundCheckBox.Value = true;
            app.savecalibrationCheckBox = uicheckbox(app.AcquisitionPanel);
            app.savecalibrationCheckBox.Text = 'save calibration';
            app.savecalibrationCheckBox.Value = true;
            app.framesEditField_2Label = uilabel(app.AcquisitionPanel);
            app.framesEditField_2Label.Text = '# frames';
            app.framesEditField_2 = uieditfield(app.AcquisitionPanel, 'numeric');
            app.framesEditField_2.Limits = [1 5000];
            app.framesEditField_2.ValueDisplayFormat = '%1.0f';
            app.framesEditField_2.Value = 1;
            app.acquirebackgroundButton = uibutton(app.AcquisitionPanel, 'push');
            app.acquirebackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @acquirebackgroundButtonPushed, true);
            app.acquirebackgroundButton.Text = 'acquire background';
            app.framesEditFieldLabel = uilabel(app.AcquisitionPanel);
            app.framesEditFieldLabel.Text = '# frames';
            app.framesbackgroundEditField = uieditfield(app.AcquisitionPanel, 'numeric');
            app.framesbackgroundEditField.Limits = [1 1000];
            app.framesbackgroundEditField.ValueDisplayFormat = '%1.0f';
            app.framesbackgroundEditField.Value = 10;
            app.filenameoptEditFieldLabel = uilabel(app.AcquisitionPanel);
            app.filenameoptEditFieldLabel.Text = 'file name (opt)';
            app.filenameoptEditField = uieditfield(app.AcquisitionPanel, 'text');
            app.filenameoptEditField.Value = 'exp*';
            app.projectfolderEditFieldLabel = uilabel(app.AcquisitionPanel);
            app.projectfolderEditFieldLabel.Text = 'project folder';
            app.projectfolderEditField = uieditfield(app.AcquisitionPanel, 'text');
            app.projectfolderEditField.Value = 'myProject';
            app.snapshotButton = uibutton(app.AcquisitionPanel, 'push');
            app.snapshotButton.ButtonPushedFcn = createCallbackFcn(app, @snapshotButtonPushed, true);
            app.snapshotButton.Text = 'snapshot';
            app.processstereoButton = uibutton(app.AcquisitionPanel, 'push');
            app.processstereoButton.Text = 'process stereo';
            app.browseButton = uibutton(app.AcquisitionPanel, 'push');
            app.browseButton.ButtonPushedFcn = createCallbackFcn(app, @browseButtonPushed, true);
            app.browseButton.Text = 'browse';
            app.Button = uibutton(app.AcquisitionPanel, 'push');
            app.Button.Icon = 'view.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.AcquisitionPanel, 'push');
            app.Button_2.Icon = 'view.png';
            app.Button_2.Text = '';
            app.PreviewtoolsPanel = uipanel(app.UIFigure);
            app.PreviewtoolsPanel.Title = 'Preview tools';
            app.FalsecolourDropDown = uidropdown(app.PreviewtoolsPanel);
            app.FalsecolourDropDown.Items = {'jet', 'HSV', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'gray', 'bone', 'copper', 'pink', 'lines'};
            app.FalsecolourDropDown.ItemsData = {'''Jet''', '''HSV''', '''Hot''', '''Cool''', '''Spring''', '', '''Summer''', '''Autumn''', '''Winter''', ' ''Gray''', ' ''Bone''', ' ''Copper''', ' ''Pink''', ' ''Lines'''};
            app.FalsecolourDropDown.ValueChangedFcn = createCallbackFcn(app, @FalsecolourDropDownValueChanged, true);
            app.FalsecolourDropDown.Value = '''Jet''';
            app.previewButton = uibutton(app.PreviewtoolsPanel, 'push');
            app.previewButton.ButtonPushedFcn = createCallbackFcn(app, @previewButtonPushed, true);
            app.previewButton.Text = 'preview';
            app.cropButton = uibutton(app.PreviewtoolsPanel, 'push');
            app.cropButton.ButtonPushedFcn = createCallbackFcn(app, @cropButtonPushed, true);
            app.cropButton.Text = 'crop';
            app.cropresetButton = uibutton(app.PreviewtoolsPanel, 'push');
            app.cropresetButton.Text = 'crop reset';
            app.closepreviewButton = uibutton(app.PreviewtoolsPanel, 'push');
            app.closepreviewButton.ButtonPushedFcn = createCallbackFcn(app, @closepreviewButtonPushed, true);
            app.closepreviewButton.Text = 'close preview';
            app.cropSelect = uidropdown(app.PreviewtoolsPanel);
            app.cropSelect.Items = {'crop cam1', 'crop cam2', 'crop cam3', 'crop cam4'};
            app.cropSelect.ItemsData = {'1', ' 2', ' 3', ' 4'};
            app.cropSelect.ValueChangedFcn = createCallbackFcn(app, @cropButtonPushed, true);
            app.cropSelect.Value = '1';
            app.falsecolourLabel = uilabel(app.PreviewtoolsPanel);
            app.falsecolourLabel.Text = 'false colour';
            app.cropselectLabel = uilabel(app.PreviewtoolsPanel);
            app.cropselectLabel.Text = 'crop select';
            app.CalibrationtoolsPanel = uipanel(app.UIFigure);
            app.CalibrationtoolsPanel.Title = 'Calibration tools';
            app.calibratepxButton = uibutton(app.CalibrationtoolsPanel, 'push');
            app.calibratepxButton.Text = 'calibrate px';
            app.squaresizemmEditFieldLabel = uilabel(app.CalibrationtoolsPanel);
            app.squaresizemmEditFieldLabel.Text = 'square size (mm)';
            app.squaresizemmEditField = uieditfield(app.CalibrationtoolsPanel, 'numeric');
            app.squaresizemmEditField.Value = 13;
            app.calibratestereoButton = uibutton(app.CalibrationtoolsPanel, 'push');
            app.calibratestereoButton.Text = 'calibrate stereo';
            app.linesizemmEditFieldLabel = uilabel(app.CalibrationtoolsPanel);
            app.linesizemmEditFieldLabel.Text = 'line size (mm)';
            app.linesizemmEditField = uieditfield(app.CalibrationtoolsPanel, 'numeric');
            app.linesizemmEditField.Value = 100;
            app.boardsizemmEditFieldLabel = uilabel(app.CalibrationtoolsPanel);
            app.boardsizemmEditFieldLabel.Text = 'board size (mm)';
            app.boardsizemmEditField = uieditfield(app.CalibrationtoolsPanel, 'numeric');
            app.boardsizemmEditField.Value = 290;
            app.CmdlogPanel = uipanel(app.UIFigure);
            app.CmdlogPanel.Title = 'Cmd log';
            app.CommandsTextArea = uitextarea(app.CmdlogPanel);
            app.CommandsTextArea.ValueChangedFcn = createCallbackFcn(app, @LoadCameraButtonPushed, true);
            app.clcButton = uibutton(app.CmdlogPanel, 'push');
            app.clcButton.ButtonPushedFcn = createCallbackFcn(app, @clcButtonPushed, true);
            app.clcButton.Text = 'clc';
            app.VideostreamPanel = uipanel(app.UIFigure);
            app.VideostreamPanel.Title = 'Video stream';
            app.CamerasettingsPanel = uipanel(app.UIFigure);
            app.CamerasettingsPanel.Title = 'Camera settings';
            app.cam1KnobLabel = uilabel(app.CamerasettingsPanel);
            app.cam1KnobLabel.Text = 'cam 1';
            app.cam1Knob = uiknob(app.CamerasettingsPanel, 'continuous');
            app.cam1Knob.Limits = [1 11];
            app.cam1Knob.MajorTicks = [1 2 3 4 5 6 7 8 9 10 11];
            app.cam1Knob.MajorTickLabels = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11'};
            app.cam1Knob.ValueChangedFcn = createCallbackFcn(app, @cam1KnobValueChanged, true);
            app.cam1Knob.MinorTicks = [1 1.5 2 2.5 3 3.5 4 4.5 5 5.5 6 6.5 7 7.5 8 8.5 9 9.5 10 10.5 11];
            app.cam1Knob.Value = 1;
            app.cam2Knob = uiknob(app.CamerasettingsPanel, 'continuous');
            app.cam2Knob.Limits = [1 11];
            app.cam2Knob.MajorTicks = [1 2 3 4 5 6 7 8 9 10 11];
            app.cam2Knob.MajorTickLabels = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11'};
            app.cam2Knob.ValueChangedFcn = createCallbackFcn(app, @cam2KnobValueChanged, true);
            app.cam2Knob.MinorTicks = [1 1.5 2 2.5 3 3.5 4 4.5 5 5.5 6 6.5 7 7.5 8 8.5 9 9.5 10 10.5 11];
            app.cam2Knob.Value = 1;
            app.cam2KnobLabel = uilabel(app.CamerasettingsPanel);
            app.cam2KnobLabel.Text = 'cam 2';
            app.cam3Knob = uiknob(app.CamerasettingsPanel, 'continuous');
            app.cam3Knob.Limits = [1 11];
            app.cam3Knob.MajorTicks = [1 2 3 4 5 6 7 8 9 10 11];
            app.cam3Knob.MajorTickLabels = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11'};
            app.cam3Knob.ValueChangedFcn = createCallbackFcn(app, @cam3KnobValueChanged, true);
            app.cam3Knob.MinorTicks = [1 1.5 2 2.5 3 3.5 4 4.5 5 5.5 6 6.5 7 7.5 8 8.5 9 9.5 10 10.5 11];
            app.cam3Knob.Value = 1;
            app.cam3KnobLabel = uilabel(app.CamerasettingsPanel);
            app.cam3KnobLabel.Text = 'cam 3';
            app.cam4Knob = uiknob(app.CamerasettingsPanel, 'continuous');
            app.cam4Knob.Limits = [1 11];
            app.cam4Knob.MajorTicks = [1 2 3 4 5 6 7 8 9 10 11];
            app.cam4Knob.MajorTickLabels = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11'};
            app.cam4Knob.ValueChangedFcn = createCallbackFcn(app, @cam4KnobValueChanged, true);
            app.cam4Knob.MinorTicks = [1 1.5 2 2.5 3 3.5 4 4.5 5 5.5 6 6.5 7 7.5 8 8.5 9 9.5 10 10.5 11];
            app.cam4Knob.Value = 1;
            app.cam4KnobLabel = uilabel(app.CamerasettingsPanel);
            app.cam4KnobLabel.Text = 'cam 4';
            app.ExposureLabel = uilabel(app.CamerasettingsPanel);
            app.ExposureLabel.Text = 'Exposure';
            app.AQfpsLabel = uilabel(app.CamerasettingsPanel);
            app.AQfpsLabel.Text = 'AQ fps';
            app.Spinner = uispinner(app.CamerasettingsPanel);
            app.Spinner.Limits = [1 200];
            app.Spinner.ValueDisplayFormat = '%0.1f';
            app.Spinner.Value = 30;
            app.Spinner_2 = uispinner(app.CamerasettingsPanel);
            app.Spinner_2.Limits = [1 200];
            app.Spinner_2.ValueDisplayFormat = '%0.1f';
            app.Spinner_2.Value = 30;
            app.Spinner_3 = uispinner(app.CamerasettingsPanel);
            app.Spinner_3.Limits = [1 200];
            app.Spinner_3.ValueDisplayFormat = '%0.1f';
            app.Spinner_3.Value = 30;
            app.Spinner_4 = uispinner(app.CamerasettingsPanel);
            app.Spinner_4.Limits = [1 200];
            app.Spinner_4.ValueDisplayFormat = '%0.1f';
            app.Spinner_4.Value = 30;
            app.CamTabGroup = uitabgroup(app.UIFigure);
            app.Cam1Tab = uitab(app.CamTabGroup);
            app.Cam1Tab.Title = 'Cam1';
            app.loadCamera_1 = uibutton(app.Cam1Tab, 'push');
            app.loadCamera_1.ButtonPushedFcn = createCallbackFcn(app, @LoadCameraButtonPushed, true);
            app.loadCamera_1.Text = 'load camera 1';
            app.resetButton = uibutton(app.Cam1Tab, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButton_1Pushed, true);
            app.resetButton.Text = 'reset';
            app.interface_1 = uidropdown(app.Cam1Tab);
            app.interface_1.Items = {'interface'};
            app.interface_1.ItemsData = {'interface'};
            app.interface_1.ValueChangedFcn = createCallbackFcn(app, @interface_1ValueChanged, true);
            app.interface_1.Value = 'interface';
            app.format_1 = uidropdown(app.Cam1Tab);
            app.format_1.Items = {'format'};
            app.format_1.ItemsData = {'format'};
            app.format_1.ValueChangedFcn = createCallbackFcn(app, @interface_1ValueChanged, true);
            app.format_1.Value = 'format';
            app.deviceID_1 = uidropdown(app.Cam1Tab);
            app.deviceID_1.Items = {'deviceID'};
            app.deviceID_1.ItemsData = {'deviceID'};
            app.deviceID_1.ValueChangedFcn = createCallbackFcn(app, @interface_1ValueChanged, true);
            app.deviceID_1.Value = 'deviceID';
            app.Cam2Tab = uitab(app.CamTabGroup);
            app.Cam2Tab.Title = 'Cam2';
            app.loadCamera_2 = uibutton(app.Cam2Tab, 'push');
            app.loadCamera_2.ButtonPushedFcn = createCallbackFcn(app, @loadCamera_2ButtonPushed, true);
            app.loadCamera_2.Text = 'load camera 2';
            app.resetButton_2 = uibutton(app.Cam2Tab, 'push');
            app.resetButton_2.ButtonPushedFcn = createCallbackFcn(app, @resetButton_1Pushed, true);
            app.resetButton_2.Text = 'reset';
            app.interface_2 = uidropdown(app.Cam2Tab);
            app.interface_2.Items = {'interface'};
            app.interface_2.ItemsData = {'interface'};
            app.interface_2.ValueChangedFcn = createCallbackFcn(app, @interface_2ValueChanged, true);
            app.interface_2.Value = 'interface';
            app.format_2 = uidropdown(app.Cam2Tab);
            app.format_2.Items = {'format'};
            app.format_2.ItemsData = {'format'};
            app.format_2.ValueChangedFcn = createCallbackFcn(app, @interface_2ValueChanged, true);
            app.format_2.Value = 'format';
            app.deviceID_2 = uidropdown(app.Cam2Tab);
            app.deviceID_2.Items = {'deviceID'};
            app.deviceID_2.ItemsData = {'deviceID'};
            app.deviceID_2.ValueChangedFcn = createCallbackFcn(app, @interface_2ValueChanged, true);
            app.deviceID_2.Value = 'deviceID';
            app.Cam3Tab = uitab(app.CamTabGroup);
            app.Cam3Tab.Title = 'Cam3';
            app.loadCamera_3 = uibutton(app.Cam3Tab, 'push');
            app.loadCamera_3.ButtonPushedFcn = createCallbackFcn(app, @loadCamera_3ButtonPushed, true);
            app.loadCamera_3.Text = 'load camera 3';
            app.resetButton_3 = uibutton(app.Cam3Tab, 'push');
            app.resetButton_3.ButtonPushedFcn = createCallbackFcn(app, @resetButton_1Pushed, true);
            app.resetButton_3.Text = 'reset';
            app.interface_3 = uidropdown(app.Cam3Tab);
            app.interface_3.Items = {'interface'};
            app.interface_3.ItemsData = {'interface'};
            app.interface_3.ValueChangedFcn = createCallbackFcn(app, @interface_3ValueChanged, true);
            app.interface_3.Value = 'interface';
            app.format_3 = uidropdown(app.Cam3Tab);
            app.format_3.Items = {'format'};
            app.format_3.ItemsData = {'format'};
            app.format_3.ValueChangedFcn = createCallbackFcn(app, @interface_3ValueChanged, true);
            app.format_3.Value = 'format';
            app.deviceID_3 = uidropdown(app.Cam3Tab);
            app.deviceID_3.Items = {'deviceID'};
            app.deviceID_3.ItemsData = {'deviceID'};
            app.deviceID_3.ValueChangedFcn = createCallbackFcn(app, @interface_3ValueChanged, true);
            app.deviceID_3.Value = 'deviceID';
            app.Cam4Tab = uitab(app.CamTabGroup);
            app.Cam4Tab.Title = 'Cam4';
            app.loadCamera_4 = uibutton(app.Cam4Tab, 'push');
            app.loadCamera_4.ButtonPushedFcn = createCallbackFcn(app, @loadCamera_4ButtonPushed, true);
            app.loadCamera_4.Text = 'load camera 4';
            app.resetButton_4 = uibutton(app.Cam4Tab, 'push');
            app.resetButton_4.ButtonPushedFcn = createCallbackFcn(app, @resetButton_1Pushed, true);
            app.resetButton_4.Text = 'reset';
            app.interface_4 = uidropdown(app.Cam4Tab);
            app.interface_4.Items = {'interface'};
            app.interface_4.ItemsData = {'interface'};
            app.interface_4.ValueChangedFcn = createCallbackFcn(app, @interface_4ValueChanged, true);
            app.interface_4.Value = 'interface';
            app.format_4 = uidropdown(app.Cam4Tab);
            app.format_4.Items = {'format'};
            app.format_4.ItemsData = {'format'};
            app.format_4.ValueChangedFcn = createCallbackFcn(app, @interface_4ValueChanged, true);
            app.format_4.Value = 'format';
            app.deviceID_4 = uidropdown(app.Cam4Tab);
            app.deviceID_4.Items = {'deviceID'};
            app.deviceID_4.ItemsData = {'deviceID'};
            app.deviceID_4.ValueChangedFcn = createCallbackFcn(app, @interface_4ValueChanged, true);
            app.deviceID_4.Value = 'deviceID';
            app.activecamsPanel = uipanel(app.UIFigure);
            app.activecamsPanel.Title = 'active cams';
            app.CheckBox_1 = uicheckbox(app.activecamsPanel);
            app.CheckBox_1.Text = '1';
            app.CheckBox_1.Value = true;
            app.CheckBox_2 = uicheckbox(app.activecamsPanel);
            app.CheckBox_2.Text = '2';
            app.CheckBox_3 = uicheckbox(app.activecamsPanel);
            app.CheckBox_3.Text = '3';
            app.CheckBox_4 = uicheckbox(app.activecamsPanel);
            app.CheckBox_4.Text = '4';
            app.lampCam_1 = uilamp(app.activecamsPanel);
            app.lampCam_2 = uilamp(app.activecamsPanel);
            app.lampCam_3 = uilamp(app.activecamsPanel);
            app.lampCam_4 = uilamp(app.activecamsPanel);
            app.quickloadButton = uibutton(app.activecamsPanel, 'push');
            app.quickloadButton.ButtonPushedFcn = createCallbackFcn(app, @quickloadButtonPushed, true);
            app.quickloadButton.Text = 'quick load';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.updateButton = uibutton(app.UIFigure, 'state');
            app.updateButton.ValueChangedFcn = createCallbackFcn(app, @updateButtonValueChanged, true);
            app.updateButton.Text = 'update';
            app.updateButton_2 = uibutton(app.UIFigure, 'state');
            app.updateButton_2.ValueChangedFcn = createCallbackFcn(app, @updateButton_2ValueChanged, true);
            app.updateButton_2.Text = 'update';
            app.VideoStream = uiaxes(app.UIFigure);
            app.HistLabel = uilabel(app.UIFigure);
            app.HistLabel.Text = 'Hist';
            app.ColorHistLabel = uilabel(app.UIFigure);
            app.ColorHistLabel.Text = 'Color Hist';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = camFlowAqApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end